classdef SAtkins_BME211_Final < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        Label                        matlab.ui.control.Label
        counter                      matlab.ui.control.Label
        CountLabel                   matlab.ui.control.Label
        DevelopedbySloanAtkinsLabel  matlab.ui.control.Label
        Lung2                        matlab.ui.control.Image
        Lung3                        matlab.ui.control.Image
        Lung1                        matlab.ui.control.Image
        Heart3                       matlab.ui.control.Image
        Heart1                       matlab.ui.control.Image
        Heart2                       matlab.ui.control.Image
        Brain3                       matlab.ui.control.Image
        Brain2                       matlab.ui.control.Image
        Brain1                       matlab.ui.control.Image
        HeartCount                   matlab.ui.control.Label
        LungCount                    matlab.ui.control.Label
        BrainCount                   matlab.ui.control.Label
        LUNGLabel                    matlab.ui.control.Label
        HEARTLabel                   matlab.ui.control.Label
        BRAINLabel                   matlab.ui.control.Label
        StartButton                  matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            counter1 = 0;
            counter2 = 0;
            counter3 = 0;
            app.BrainCount.Text = num2str(0);
            app.HeartCount.Text = num2str(0);
            app.LungCount.Text = num2str(0);
            count = randperm(9,1);
            app.Label.Text = num2str(count);
            a = 0;
            while (a < count)
                p = randperm(9,3);
                app.counter.Text = num2str(a+1);
                if ismember(1, p)
                    set(app.Brain1,'visible','on')
                    counter1 = counter1 + 10;
                    app.BrainCount.Text = num2str(counter1);
                else
                    set(app.Brain1,'visible','off')
                end
        
                if ismember(2, p)
                    set(app.Brain2,'visible','on')
                    counter1 = counter1 + 10;
                    app.BrainCount.Text = num2str(counter1);
                else
                    set(app.Brain2,'visible','off')
                end
        
                if ismember(3, p)
                    set(app.Brain3,'visible','on')
                    counter1 = counter1 + 10;
                    app.BrainCount.Text = num2str(counter1);
                else
                    set(app.Brain3, 'visible','off')
                end
                
                if ismember(4, p)
                    set(app.Heart1,'visible','on')
                    counter2 = counter2 + 10;
                    app.HeartCount.Text = num2str(counter2);
                else
                    set(app.Heart1, 'visible','off')
                end
        
                if ismember(5, p)
                    set(app.Heart2,'visible','on')
                    counter2 = counter2 + 10;
                    app.HeartCount.Text = num2str(counter2);
                else
                    set(app.Heart2, 'visible','off')
                end
        
                if ismember(6, p)
                    set(app.Heart3,'visible','on')
                    counter2 = counter2 + 10;
                    app.HeartCount.Text = num2str(counter2);
                else
                    set(app.Heart3, 'visible','off')
                end
        
                if ismember(7, p)
                    set(app.Lung1,'visible','on')
                    counter3 = counter3 + 10;
                    app.LungCount.Text = num2str(counter3);
                else
                    set(app.Lung1, 'visible','off')
                end
        
                if ismember(8, p)
                    set(app.Lung2,'visible','on')
                    counter3 = counter3 + 10;
                    app.LungCount.Text = num2str(counter3);
                else
                    set(app.Lung2, 'visible','off')
                end
        
                if ismember(9, p)
                    set(app.Lung3,'visible','on')
                    counter3 = counter3 + 10;
                    app.LungCount.Text = num2str(counter3);
                else
                    set(app.Lung3, 'visible','off')
                end
                pause(1);
                a = a+1
                
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.BRAINLabel = uilabel(app.UIFigure);
            app.BRAINLabel.Text = 'BRAIN';
            app.HEARTLabel = uilabel(app.UIFigure);
            app.HEARTLabel.Text = 'HEART';
            app.LUNGLabel = uilabel(app.UIFigure);
            app.LUNGLabel.Text = 'LUNG';
            app.BrainCount = uilabel(app.UIFigure);
            app.BrainCount.Text = '0';
            app.LungCount = uilabel(app.UIFigure);
            app.LungCount.Text = '0';
            app.HeartCount = uilabel(app.UIFigure);
            app.HeartCount.Text = '0';
            app.Brain1 = uiimage(app.UIFigure);
            app.Brain1.ImageSource = 'brain.jpg';
            app.Brain2 = uiimage(app.UIFigure);
            app.Brain2.ImageSource = 'brain.jpg';
            app.Brain3 = uiimage(app.UIFigure);
            app.Brain3.ImageSource = 'brain.jpg';
            app.Heart2 = uiimage(app.UIFigure);
            app.Heart2.ImageSource = 'heart.jpg';
            app.Heart1 = uiimage(app.UIFigure);
            app.Heart1.ImageSource = 'heart.jpg';
            app.Heart3 = uiimage(app.UIFigure);
            app.Heart3.ImageSource = 'heart.jpg';
            app.Lung1 = uiimage(app.UIFigure);
            app.Lung1.ImageSource = 'lung.jpg';
            app.Lung3 = uiimage(app.UIFigure);
            app.Lung3.ImageSource = 'lung.jpg';
            app.Lung2 = uiimage(app.UIFigure);
            app.Lung2.ImageSource = 'lung.jpg';
            app.DevelopedbySloanAtkinsLabel = uilabel(app.UIFigure);
            app.DevelopedbySloanAtkinsLabel.Text = 'Developed by Sloan Atkins';
            app.CountLabel = uilabel(app.UIFigure);
            app.CountLabel.Text = 'Count';
            app.counter = uilabel(app.UIFigure);
            app.counter.Text = '0';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '0';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SAtkins_BME211_Final

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end