classdef SAtkins_BME211_Homework3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        StartButton       matlab.ui.control.StateButton
        AtkinsSloanLabel  matlab.ui.control.Label
        NALabel           matlab.ui.control.Label
        HeartRateLabel    matlab.ui.control.Label
        Image             matlab.ui.control.Image
        ecgSignal         matlab.ui.control.UIAxes
    end

    properties (Access = private)
        ecgsig;
        counter;
        sigLen;
        wLen;
        time;
        peaks;
        heartRateCycle;
        oneSecWin;% Description
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            load('ecgBME211.mat', 'ecgSig', 'peakIndices', 't')

            app.time = t;
            app.peaks = peakIndices;
            app.ecgsig = ecgSig;

            app.oneSecWin = 360;
            howManySec = 5;
            app.heartRateCycle = 60/howManySec;
            app.wLen = howManySec*app.oneSecWin;
            app.counter = 1;
            app.sigLen = length(app.ecgsig);

            plot(app.ecgSignal, app.time, app.ecgsig, 'b', 'LineWidth', 2);
            hold(app.ecgSignal, 'on');
            plot(app.ecgSignal, app.time(app.peaks), app.ecgsig(app.peaks), 'rx', 'LineWidth', 2);
            app.ecgSignal.XLim = [app.time(app.counter) app.time(app.counter + app.wLen)];
            heart_rate = length(find(app.counter<app.peaks & app.peaks<app.wLen))*app.heartRateCycle;
            app.NALabel.Text=num2str(heart_rate);
            hold(app.ecgSignal,'off');

        end

        % Value changed function: StartButton
        function StartButtonValueChanged(app, event)
            %value = app.StartButton.Value;
            if(app.StartButton.Value ==1)
                app.StartButton.Text = 'Stop';
                app.StartButton.FontColor = 'red';
                while app.counter < (app.sigLen-app.wLen+app.oneSecWin)
                    if (app.StartButton.Value ~= 1)
                        break;
                    else
                        plot(app.ecgSignal, app.time, app.ecgsig, 'b', 'LineWidth',2);
                        hold(app.ecgSignal, 'on');
                        plot(app.ecgSignal, app.time(app.peaks), app.ecgsig(app.peaks), 'rx', 'LineWidth', 2);

                        app.ecgSignal.XLim = [app.time(app.counter) app.time(app.counter+app.wLen)];
                        heart_rate=length(find(app.counter<app.peaks & app.peaks < (app.counter + app.wLen)))*app.heartRateCycle;
                        app.NALabel.Text = num2str(heart_rate);
                        drawnow;
                        pause(1);

                        hold(app.ecgSignal,'off');
                        app.counter = app.counter+app.oneSecWin;
                    end
                end
                if app.counter == (app.sigLen-app.wLen+app.oneSecWin)
                    app.StartButton.Text = 'Restart';
                    app.StartButton.FontColor= 'black';
                    app.counter = 3;
                    app.StartButton.Value = 0;
                end
            else
                app.StartButton.Text = 'Resume';
                app.StartButton.FontColor = 'blue';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ecgSignal = uiaxes(app.UIFigure);
            title(app.ecgSignal, 'ECG Signal')
            xlabel(app.ecgSignal, 'Time(sec)')
            ylabel(app.ecgSignal, 'Amplitude(mV)')
            zlabel(app.ecgSignal, 'Z')
            app.ecgSignal.XTickLabel = {'0'; '0.1'; '0.2'; '0.3'; '0.4'; '0.5'; '0.6'; '0.7'; '0.8'; '0.9'; '1'};
            app.ecgSignal.YTickLabel = {'-2'; '-1.5'; '-1'; '-0.5'; '0'; '0.5'; '1'; '1.5'; '2'};
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'ecg_icon.jpg');
            app.HeartRateLabel = uilabel(app.UIFigure);
            app.HeartRateLabel.Text = 'Heart Rate';
            app.NALabel = uilabel(app.UIFigure);
            app.NALabel.Text = 'N/A';
            app.AtkinsSloanLabel = uilabel(app.UIFigure);
            app.AtkinsSloanLabel.Text = 'Atkins, Sloan';
            app.StartButton = uibutton(app.UIFigure, 'state');
            app.StartButton.ValueChangedFcn = createCallbackFcn(app, @StartButtonValueChanged, true);
            app.StartButton.Text = 'Start';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SAtkins_BME211_Homework3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end